classdef deneme2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AddCityButton         matlab.ui.control.Button
        DropDown              matlab.ui.control.DropDown
        DropDownLabel         matlab.ui.control.Label
        EditField             matlab.ui.control.EditField
        EditFieldLabel        matlab.ui.control.Label
        GetInformationButton  matlab.ui.control.Button
        WindSpeedAxis         matlab.ui.control.UIAxes
        HumidityAxis          matlab.ui.control.UIAxes
        TempAxis              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: AddCityButton, EditField
        function AddCityButtonPushed(app, event)
            %Get the new city name from the edit field
    newCityName = app.EditField.Value;

    % Ensure the new city name is a cell array
    newCityNameCell = {newCityName};

    % Add the new city to the dropdown menu
    app.DropDown.Items = [app.DropDown.Items, newCityNameCell];

    % Select the new city in the dropdown menu
    app.DropDown.Value = newCityName;
        end

        % Button pushed function: GetInformationButton
        function GetInformationButtonPushed(app, event)
            % Get the selected city and API key
            cityName = app.Dropdown.Value;
            apiKey = '0de2ef6646e30c5a61a344c227981893';

           % Retrieve the weather data for the selected city
           weatherData = fetchFiveDayForecast(cityName, apiKey);

            % Update the temperature graph
            app.TempAxis.XData = weatherData.Time;
            app.TempAxis.YData = weatherData.Temperature;
            title(app.TempAxis, ['Temperature in ', cityName]);

            % Update the humidity graph
            app.HumidityAxis.XData = weatherData.Time;
            app.HumidityAxis.YData = weatherData.Humidity;
            title(app.HumidityAxis, ['Humidity in ', cityName]);

            % Update the wind speed graph
            app.WindSpeedAxis.XData = weatherData.Time;
           app.WindSpeedAxis.YData = weatherData.WindSpeed;
            title(app.WindSpeedAxis, ['Wind Speed in ', cityName]);

            % Update the current conditions display
            currentConditions = sprintf('Temperature: %.1f°C\nHumidity: %d%%\nWind Speed: %.1f m/s', weatherData.Temperature(1), weatherData.Humidity(1), weatherData.WindSpeed(1));
           app.CityNameLabel.String = ['Selected City: ', cityName];
            app.CurrentConditionsText.String = currentConditions;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TempAxis = uiaxes(app.UIFigure);
            title(app.TempAxis, 'Temperature')
            xlabel(app.TempAxis, 'Date')
            ylabel(app.TempAxis, 'Temperature (°C)')
            app.HumidityAxis = uiaxes(app.UIFigure);
            title(app.HumidityAxis, 'Humdity')
            xlabel(app.HumidityAxis, 'Date')
            ylabel(app.HumidityAxis, 'Humidity (%)')
            app.WindSpeedAxis = uiaxes(app.UIFigure);
            title(app.WindSpeedAxis, 'Wind Speed')
            xlabel(app.WindSpeedAxis, 'Date')
            ylabel(app.WindSpeedAxis, 'Wind Speed (m/s)')
            app.GetInformationButton = uibutton(app.UIFigure, 'push');
            app.GetInformationButton.ButtonPushedFcn = createCallbackFcn(app, @GetInformationButtonPushed, true);
            app.GetInformationButton.Text = 'Get Information';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @AddCityButtonPushed, true);
            app.EditField.Value = 'Add City';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Muğla', 'Antalya', 'Kars', 'Istanbul'};
            app.DropDown.Value = 'Muğla';
            app.AddCityButton = uibutton(app.UIFigure, 'push');
            app.AddCityButton.ButtonPushedFcn = createCallbackFcn(app, @AddCityButtonPushed, true);
            app.AddCityButton.Text = 'Add City';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deneme2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end